classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HIDITUIFigure                 matlab.ui.Figure
        AlphaEditField_3              matlab.ui.control.NumericEditField
        AlphaEditField_3Label         matlab.ui.control.Label
        MostrarButton                 matlab.ui.control.Button
        AlphaEditField_2              matlab.ui.control.NumericEditField
        AlphaEditField_2Label         matlab.ui.control.Label
        SerecomiendaguardarlaimagenenformatoPNGLabel  matlab.ui.control.Label
        ImagenoriginalButton          matlab.ui.control.Button
        ImagenconmarcadeaguaButton    matlab.ui.control.Button
        TextoaesconderEditField       matlab.ui.control.EditField
        TextoaesconderEditFieldLabel  matlab.ui.control.Label
        Image4                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        ConfirmartextoButton          matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        EsconderButton                matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        ElegirlaimagenquedeseaesconderButton  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        ElegirimagendndedeseeesconderelmensajeButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        band = 0
        band2 = 0
        band3=0
        band4=0
        img1
        img2
        img3
        img4
        %properties para poder pasar las variables de funcipona función  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % ElegirimagendndedeseeesconderelmensajeButton
        function ElegirimagendndedeseeesconderelmensajeButtonPushed(app, event)
            app.AlphaEditField_2.Value = 0.04;
            try
                [filename, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                s = strcat(pathname,filename);
                app.img1 =  imread(s);
                app.Image.ImageSource = app.img1;
                app.band = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: ElegirlaimagenquedeseaesconderButton
        function ElegirlaimagenquedeseaesconderButtonPushed(app, event)
            app.AlphaEditField_2.Value = 0.04;
            try
                [filename2, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                s = strcat(pathname,filename2);
                app.img2=imread(s);
                app.Image2.ImageSource = app.img2;
                app.band2 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: EsconderButton
        function EsconderButtonPushed(app, event)
            try
                if app.band == 1 && app.band2 == 1 % Para saber si cargaron las dos imágenes
                    alpha=app.AlphaEditField_2.Value;
                    Ra = dct2(double(imresize(app.img1(:,:,1), [300,300])));
                    Ga = dct2(double(imresize(app.img1(:,:,2), [300,300])));
                    Ba = dct2(double(imresize(app.img1(:,:,3), [300,300])));
                    Rb = dct2(double(imresize(app.img2(:,:,1), [300,300])));
                    Gb = dct2(double(imresize(app.img2(:,:,2), [300,300])));
                    Bb = dct2(double(imresize(app.img2(:,:,3), [300,300])));

                    Rc = Ra + alpha*Rb;
                    Gc = Ga + alpha*Gb;
                    Bc = Ba + alpha*Bb;

                    Riw = idct2(Rc);
                    Giw = idct2(Gc);
                    Biw = idct2(Bc);
    
                    iw=cat(3, Riw, Giw, Biw);

                    figure();imshow(uint8(iw));imsave();
    
                    app.Label.Text = ' ';
                else
                    app.Label.Text = 'Seleccione las imágenes o el texto';
                end
            catch
                app.Label.Text = 'Ocurrió un error, intente de nuevo';
            end

        end

        % Button pushed function: ConfirmartextoButton
        function ConfirmartextoButtonPushed(app, event)
            try
                text = app.TextoaesconderEditField.Value;
                [filename3, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen de fondo para el texto');
                s = strcat(pathname,filename3);
                I = imread(s);
                [ySize, xSize] = size(I);
                if ySize > xSize
                    FS = round(ySize/30);
                else
                    FS = round(xSize/30);
                end
                if FS > 200
                    FS = 200;
                end
                position = [5 5];
                RGB = insertText(I,position,text, 'FontSize', FS, 'BoxColor','white','BoxOpacity',1); 
                app.img2=RGB;
                app.img2=imresize(app.img2,[300,300]);
                app.Image2.ImageSource = RGB;
                app.band2 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Ocurrió un error, intente de nuevo';
            end
        end

        % Button pushed function: ImagenconmarcadeaguaButton
        function ImagenconmarcadeaguaButtonPushed(app, event)
            try
                [filename4, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                s = strcat(pathname,filename4);
                app.img3=imread(s);
                app.Image3.ImageSource = app.img3;
                app.band3 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: ImagenoriginalButton
        function ImagenoriginalButtonPushed(app, event)
            try
                [filename5, pathname] = uigetfile({'*','Imagen'}, 'Seleccione una imagen');
                s = strcat(pathname,filename5);
                app.img4=imread(s);
                app.Image4.ImageSource = app.img4;
                app.band4 = 1;
                app.Label.Text = ' ';
            catch
                app.Label.Text = 'Seleccione otra imagen';
            end
        end

        % Button pushed function: MostrarButton
        function MostrarButtonPushed(app, event)
            try
                if app.band3 == 1 && app.band4 == 1
                    alpha2=app.AlphaEditField_3.Value;
                    Ra = dct2(double(imresize(app.img4(:,:,1), [300,300])));
                    Ga = dct2(double(imresize(app.img4(:,:,2), [300,300])));
                    Ba = dct2(double(imresize(app.img4(:,:,3), [300,300])));
        
                    Rc = dct2(double(app.img3(:,:,1)));
                    Gc = dct2(double(app.img3(:,:,2)));
                    Bc = dct2(double(app.img3(:,:,3)));
        
                    Rb = (Rc-Ra)./alpha2;
                    Gb = (Gc-Ga)./alpha2;
                    Bb = (Bc-Ba)./alpha2;
                    Rb = idct2(Rb);
                    Gb = idct2(Gb);
                    Bb = idct2(Bb);
                    recup = cat(3, Rb,Gb,Bb);
                    figure();imshow(abs(uint8(recup))); title('Imagen recuperada'); 
                    app.Label.Text = ' ';
                else
                    app.Label.Text = 'Seleccione las imágenes';
                end
            catch
                app.Label.Text = 'Ocurrió un error, intente de nuevo';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HIDITUIFigure = uifigure('Visible', 'off');
            app.HIDITUIFigure.Name = 'HID-IT';
            app.ElegirimagendndedeseeesconderelmensajeButton = uibutton(app.HIDITUIFigure, 'push');
            app.ElegirimagendndedeseeesconderelmensajeButton.ButtonPushedFcn = createCallbackFcn(app, @ElegirimagendndedeseeesconderelmensajeButtonPushed, true);
            app.ElegirimagendndedeseeesconderelmensajeButton.Text = {'Elegir imagen dónde desee'; 'esconder el mensaje'};
            app.Image = uiimage(app.HIDITUIFigure);
            app.ElegirlaimagenquedeseaesconderButton = uibutton(app.HIDITUIFigure, 'push');
            app.ElegirlaimagenquedeseaesconderButton.ButtonPushedFcn = createCallbackFcn(app, @ElegirlaimagenquedeseaesconderButtonPushed, true);
            app.ElegirlaimagenquedeseaesconderButton.Text = {'Elegir la imagen que'; 'desea esconder'};
            app.Image2 = uiimage(app.HIDITUIFigure);
            app.EsconderButton = uibutton(app.HIDITUIFigure, 'push');
            app.EsconderButton.ButtonPushedFcn = createCallbackFcn(app, @EsconderButtonPushed, true);
            app.EsconderButton.Text = 'Esconder';
            app.Label = uilabel(app.HIDITUIFigure);
            app.Label.Text = '';
            app.ConfirmartextoButton = uibutton(app.HIDITUIFigure, 'push');
            app.ConfirmartextoButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmartextoButtonPushed, true);
            app.ConfirmartextoButton.Text = 'Confirmar texto';
            app.Image3 = uiimage(app.HIDITUIFigure);
            app.Image4 = uiimage(app.HIDITUIFigure);
            app.TextoaesconderEditFieldLabel = uilabel(app.HIDITUIFigure);
            app.TextoaesconderEditFieldLabel.Text = 'Texto a esconder';
            app.TextoaesconderEditField = uieditfield(app.HIDITUIFigure, 'text');
            app.ImagenconmarcadeaguaButton = uibutton(app.HIDITUIFigure, 'push');
            app.ImagenconmarcadeaguaButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenconmarcadeaguaButtonPushed, true);
            app.ImagenconmarcadeaguaButton.Text = {'Imagen con '; 'marca de agua'};
            app.ImagenoriginalButton = uibutton(app.HIDITUIFigure, 'push');
            app.ImagenoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenoriginalButtonPushed, true);
            app.ImagenoriginalButton.Text = 'Imagen original';
            app.SerecomiendaguardarlaimagenenformatoPNGLabel = uilabel(app.HIDITUIFigure);
            app.SerecomiendaguardarlaimagenenformatoPNGLabel.Text = {'Se recomienda guardar '; 'la imagen en formato PNG'};
            app.AlphaEditField_2Label = uilabel(app.HIDITUIFigure);
            app.AlphaEditField_2Label.Text = 'Alpha =';
            app.AlphaEditField_2 = uieditfield(app.HIDITUIFigure, 'numeric');
            app.MostrarButton = uibutton(app.HIDITUIFigure, 'push');
            app.MostrarButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarButtonPushed, true);
            app.MostrarButton.Text = 'Mostrar';
            app.AlphaEditField_3Label = uilabel(app.HIDITUIFigure);
            app.AlphaEditField_3Label.Text = 'Alpha =';
            app.AlphaEditField_3 = uieditfield(app.HIDITUIFigure, 'numeric');
            % Show the figure after all components are created
            app.HIDITUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HIDITUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HIDITUIFigure)
        end
    end
end